classdef gait_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab1                      matlab.ui.container.Tab
        UploadDataButton          matlab.ui.control.Button
        MessageTextArea           matlab.ui.control.TextArea
        TextfromMCULabel          matlab.ui.control.Label
        BluetoothConnectionPanel  matlab.ui.container.Panel
        StatusLamp                matlab.ui.control.Lamp
        StatusLabel               matlab.ui.control.Label
        DisconnectButton          matlab.ui.control.Button
        ConnectButton             matlab.ui.control.Button
        DeviceListDropDown        matlab.ui.control.DropDown
        DeviceListDropDownLabel   matlab.ui.control.Label
        ScanButton                matlab.ui.control.Button
        BatteryAxes               matlab.ui.control.UIAxes
        DataVisualizationTab      matlab.ui.container.Tab
        LoadPlotButton            matlab.ui.control.Button
        StepAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        BLEDevice   % ble object
        DeviceTable % result of blelist command
        IsConnected = false
        TextChar    % The characteristic object
        DataTimer   % Timer to poll for data since 'subscribe' is missing
        SaveDir = '' % Directory for auto-named daily CSV files
        DataJustCleared = false % True after successful upload+SD clear; reset on disconnect
        BatteryFillRect % handle to the colored fill rectangle
        BatteryPctText % handle to the "XX%" text
    end
    
    methods (Access = private)
        
        % Timer Callback: Reads and parses battery data every 0.1 seconds
        function pollData(app, ~, ~)
            try
                if ~app.IsConnected || isempty(app.TextChar)
                    return;
                end

                data = read(app.TextChar);
        
                if ~isempty(data)
                    msg = strtrim(string(char(data)));
        
                    % Parse CSV: "VBAT:3.901,PCT:82"
                    parts = strsplit(msg, ',');
        
                    if numel(parts) >= 2
                        vbat = str2double(extractAfter(parts{1}, ':'));
                        pct  = str2double(extractAfter(parts{2}, ':'));
        
                        % Update battery gauge
                        if ~isnan(pct)
                            app.drawBattery(pct);
                        end
        
                        % Update text area
                        if ~isnan(pct) && ~isnan(vbat)
                            timestamp = char(datetime("now", "Format", "HH:mm:ss"));
                            line1 = sprintf('[%s]  BAT: %.3f V (%d%%)', timestamp, vbat, int32(pct));
                            app.MessageTextArea.Value = {line1};
                            drawnow;
                        end
        
                    else
                        % Fallback: raw string
                        timestamp = char(datetime("now", "Format", "HH:mm:ss"));
                        app.MessageTextArea.Value = {sprintf('[%s] RAW: %s', timestamp, char(msg))};
                        drawnow;
                    end
                end
        
            catch ME
                fprintf('Timer Error: %s\n', ME.message);
            end
        end

        function handleSyncNotification(app, src, ~)
            try
                data = read(src);
                chunk = char(data);
        
                % Filter out battery reports that may sneak in
                chunk = regexprep(chunk, 'VBAT:[0-9.]+,PCT:[0-9]+', '');
        
                if isempty(chunk)
                    return;
                end
        
                if contains(chunk, 'EOF')
                    before = extractBefore(string(chunk), 'EOF');
                    app.SyncBuffer = [app.SyncBuffer, uint8(char(before))];
                    app.SyncDone = true;
                else
                    app.SyncBuffer = [app.SyncBuffer, uint8(chunk)];
                end
            catch
            end
        end

        % Draw a Windows/iOS-style horizontal battery bar into BatteryAxes.
        function drawBattery(app, pct)
            if isempty(app.BatteryAxes) || ~isvalid(app.BatteryAxes)
                return;
            end
            pct = max(0, min(100, pct));

            ax = app.BatteryAxes;

            % First-time setup: draw the static chrome once
            if isempty(app.BatteryFillRect) || ~isvalid(app.BatteryFillRect)
                cla(ax);
                hold(ax, 'on');
                axis(ax, 'off');
                axis(ax, 'equal');
                xlim(ax, [0 12]);
                ylim(ax, [0 5]);

                % Body outline
                plot(ax, [0.3 10 10 0.3 0.3], [1 1 4 4 1], ...
                    'k-', 'LineWidth', 2);

                % Positive terminal nub
                rectangle(ax, 'Position', [10 2.1 0.7 0.8], ...
                    'FaceColor', [0 0 0], 'EdgeColor', 'none');

                % Fill rectangle — created once with zero width, updated below
                app.BatteryFillRect = rectangle(ax, ...
                    'Position', [0.5 1.2 0.01 2.6], ...
                    'FaceColor', [0.2 0.75 0.3], 'EdgeColor', 'none');

                % Percentage label — created once, updated below
                app.BatteryPctText = text(ax, 5.15, 2.5, '', ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle', ...
                    'FontSize', 14, 'FontWeight', 'bold', 'Color', [0 0 0]);

                hold(ax, 'off');
            end

            % Incremental update: only the fill width/color and the text
            if pct > 50
                fillColor = [0.20 0.75 0.30];   % green
            elseif pct > 20
                fillColor = [1.00 0.75 0.00];   % amber
            else
                fillColor = [0.90 0.20 0.20];   % red
            end

            innerX = 0.5;  innerY = 1.2;  innerW = 9.3;  innerH = 2.6;
            fillW  = max(0.01, innerW * (pct / 100));

            app.BatteryFillRect.Position  = [innerX innerY fillW innerH];
            app.BatteryFillRect.FaceColor = fillColor;
            app.BatteryPctText.String     = sprintf('%d%%', round(pct));
        end

        % Hide battery graphics and clear the message area.
        % Called on startup, disconnect, and failed connect.
        function resetDisconnectedUI(app)
            if ~isempty(app.BatteryAxes) && isvalid(app.BatteryAxes)
                cla(app.BatteryAxes);
                app.BatteryAxes.Visible = 'off';
                title(app.BatteryAxes, '');
            end
            app.BatteryFillRect = [];
            app.BatteryPctText  = [];

            if ~isempty(app.MessageTextArea) && isvalid(app.MessageTextArea)
                app.MessageTextArea.Value = {''};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.StatusLabel.Text = "Scanning...";
            app.StatusLamp.Color = [1 1 0]; 
            app.DeviceTable = blelist; 
            
            if isempty(app.DeviceTable)
                app.StatusLabel.Text = "No BLE devices found";
                app.StatusLamp.Color = [1 0 0]; 
                return;
            end
            
            % Populate dropdown
            items = strcat(app.DeviceTable.Name, " (", app.DeviceTable.Address, ")");
            app.DeviceListDropDown.Items = items;
            app.StatusLabel.Text = "Scan complete";
            app.StatusLamp.Color = [1 1 0];

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.DeviceListDropDown.Value), return; end
            
            app.BLEDevice = []; 
            app.TextChar = [];
            
            try
                selected = app.DeviceListDropDown.Value;
                addr = extractBetween(selected, "(", ")");
                
                app.StatusLabel.Text = "Connecting...";
                app.StatusLamp.Color = [1 1 0];
                drawnow;

                % 1. Connect
                app.BLEDevice = ble(char(addr{1}));
                
                % 2. CASCADE: Try to find the characteristic using multiple methods
                servUUID = '12345678-1234-5678-1234-56789ABCDEF0';
                charUUID = '12345678-1234-5678-1234-56789ABCDEF1';
                
                success = false;
                
                % METHOD A: The Standard 3-Argument (Service UUID, Char UUID)
                if ~success
                    try
                        app.TextChar = characteristic(app.BLEDevice, servUUID, charUUID);
                        success = true;
                        fprintf('Connected using Method A (Standard UUIDs)\n');
                    catch
                    end
                end
                
                % METHOD B: Using Names from the table (Generic Access)
                % Sometimes older versions prefer the string "Name" over UUID
                if ~success
                    try
                        % Try to find the names in the table
                        tbl = app.BLEDevice.Characteristics;
                        row = strcmpi(tbl.CharacteristicUUID, charUUID);
                        if any(row)
                             sName = tbl.ServiceName(row);
                             cName = tbl.CharacteristicName(row);
                             % Try passing the Names (as char vectors)
                             if iscell(sName), sName = sName{1}; end
                             if iscell(cName), cName = cName{1}; end
                             
                             app.TextChar = characteristic(app.BLEDevice, char(sName), char(cName));
                             success = true;
                             fprintf('Connected using Method B (Names)\n');
                        end
                    catch
                    end
                end

                % METHOD C: The "Row Index" or "Single UUID" fallback
                if ~success
                    try
                        % Some very specific versions allow just the Characteristic UUID
                        app.TextChar = characteristic(app.BLEDevice, charUUID);
                        success = true;
                        fprintf('Connected using Method C (Single UUID)\n');
                    catch
                    end
                end
                
                if ~success
                     error("Could not create Characteristic object. All syntax methods failed.");
                end
                
                app.IsConnected = true;
                app.StatusLabel.Text = "Connected!";
                app.StatusLamp.Color = [0 1 0];

                % Reset display on fresh connection
                app.BatteryAxes.Visible = 'on';
                title(app.BatteryAxes, 'Battery Percentage');
                app.drawBattery(0);
                app.MessageTextArea.Value = {''};
                
                % 3. Start Polling Timer (Since 'subscribe' is missing)
                if ~isempty(app.DataTimer)
                    stop(app.DataTimer);
                    delete(app.DataTimer);
                end
                
                % Create a timer that fires 10 times a second
                app.DataTimer = timer('ExecutionMode', 'fixedRate', ...
                                      'Period', 0.1, ...
                                      'TimerFcn', @(~,~)app.pollData);
                start(app.DataTimer);
                fprintf('Polling timer started.\n');
        
            catch ME 
                app.StatusLabel.Text = "Connection failed";
                app.StatusLamp.Color = [1 0 0];
                app.resetDisconnectedUI();
                fprintf('ERROR: %s\n', ME.message);
                
                % EMERGENCY DEBUG
                % If it fails, run this line in your Command Window to see the help docs:
                % help ble/characteristic
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.IsConnected = false;
            app.DataJustCleared = false; % Allow upload after re-connect + new recording

            % Stop the timer first!
            if ~isempty(app.DataTimer)
                stop(app.DataTimer); 
                delete(app.DataTimer);
                app.DataTimer = [];
            end

            app.TextChar = [];
            app.BLEDevice = [];

            app.resetDisconnectedUI();
    
            app.StatusLabel.Text = "Disconnected";
            app.StatusLamp.Color = [1 0 0];

        end

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
            if ~app.IsConnected
                uialert(app.UIFigure, 'Not connected to device.', 'Error');
                return;
            end
        
            % Deny upload if SD was just cleared — no new sessions exist yet
            if app.DataJustCleared
                uialert(app.UIFigure, ...
                    'Data was just uploaded. Disconnect, record new sessions, then upload again.', ...
                    'No New Data');
                return;
            end

            % Stop battery polling during transfer
            if ~isempty(app.DataTimer)
                stop(app.DataTimer);
            end
        
            app.MessageTextArea.Value = {'Requesting data from device...'};
            drawnow;
        
            try
                allData = '';
                timeoutSec = 60;
                tStart = tic;
                chunkCount = 0;
        
                % Send SYNC — Arduino opens all session files and writes first chunk
                write(app.TextChar, uint8('SYNC'));
                pause(0.2);
        
                while toc(tStart) < timeoutSec
                    raw = read(app.TextChar);
                    chunk = char(raw);
                
                    % Filter stray battery reports
                    chunk = regexprep(chunk, 'VBAT:[0-9.]+,PCT:[0-9]+', '');
                
                    % [EOF] is the ONLY stop signal — do not break on partial chunks
                    % (partial chunks occur at the end of every session file, not just the last)
                    if contains(chunk, '[EOF]')
                        before = char(extractBefore(string(chunk), '[EOF]'));
                        if ~isempty(strtrim(before))
                            allData = [allData, before];
                        end
                        app.MessageTextArea.Value = {sprintf('Transfer complete! %d bytes in %d chunks', length(allData), chunkCount)};
                        drawnow;
                        break;
                    end
                
                    % Skip control command echoes (appear when MATLAB reads before Arduino writes)
                    chunkTrimmed = strtrim(chunk);
                    if strcmp(chunkTrimmed, 'NEXT') || strcmp(chunkTrimmed, 'SYNC') || isempty(chunkTrimmed)
                        write(app.TextChar, uint8('NEXT'));
                        pause(0.2);
                        continue;
                    end
                
                    % Valid data chunk — accumulate it
                    chunkCount = chunkCount + 1;
                    rawLen = length(raw);
                    allData = [allData, chunk];
                    app.MessageTextArea.Value = {sprintf('Receiving... %d bytes (chunk %d)', length(allData), chunkCount)};
                    drawnow;
                
                    % Send NEXT for all chunks.
                    % Small chunks (< 240) mark end of a session file — give Arduino extra time
                    % to open the next session file or write [EOF] before we read again.
                    write(app.TextChar, uint8('NEXT'));
                    if rawLen < 240
                        pause(0.4);  % Extra time: Arduino is advancing to next session or writing [EOF]
                    else
                        pause(0.2);
                    end
                end
        
                % Remove duplicate headers that appear between session files
                allData = regexprep(allData, 'timestamp_ms,step_type\s*[\r\n]+', '');
                % Ensure every data row ends with a newline (sessions may lack trailing newline)
                allData = regexprep(allData, '(CORRECT|INCORRECT)(?!\r?\n)', sprintf('$1\n'));


                if isempty(strtrim(allData))
                    app.MessageTextArea.Value = {'No step data on device.'};
        
                else
                    % --- Auto-save to a dated daily file ---
        
                    % On first use (or if SaveDir was cleared), ask for a folder once
                    if isempty(app.SaveDir) || ~isfolder(app.SaveDir)
                        app.SaveDir = uigetdir(pwd, 'Select folder to save daily gait files');
                        if app.SaveDir == 0
                            app.SaveDir = '';
                            app.MessageTextArea.Value = {'Save cancelled. No folder selected.'};
                            if ~isempty(app.DataTimer) && strcmp(app.DataTimer.Running, 'off')
                                start(app.DataTimer);
                            end
                            return;
                        end
                    end
        
                    % Build dated filename: gait_YYYY-MM-DD.csv
                    dateStr  = char(datetime('now', 'Format', 'yyyy-MM-dd'));
                    fileName = sprintf('gait_%s.csv', dateStr);
                    fullPath = fullfile(app.SaveDir, fileName);
        
                    % Append if today's file exists, create fresh if not.
                    % If fopen fails (most commonly because Excel has the file open),
                    % warn the user and abort WITHOUT sending DONE — so nothing on
                    % the device gets deleted and they can retry after closing Excel.
                    if isfile(fullPath)
                        fid = fopen(fullPath, 'a');
                        if fid == -1
                            uialert(app.UIFigure, ...
                                sprintf(['Could not open daily file for writing:\n%s\n\n' ...
                                         'It is likely open in Excel or another program.\n' ...
                                         'Close it and press Upload Data again.'], fullPath), ...
                                'File Locked');
                            app.MessageTextArea.Value = {'Upload aborted: daily CSV is locked.'};
                            if ~isempty(app.DataTimer) && strcmp(app.DataTimer.Running, 'off')
                                start(app.DataTimer);
                            end
                            return;
                        end
                        fprintf(fid, '%s', allData);
                        fclose(fid);
                        app.MessageTextArea.Value = {
                            sprintf('Appended %d bytes to:', length(allData));
                            fullPath};
                    else
                        fid = fopen(fullPath, 'w');
                        if fid == -1
                            uialert(app.UIFigure, ...
                                sprintf(['Could not create daily file:\n%s\n\n' ...
                                         'Check folder permissions, then press Upload Data again.'], fullPath), ...
                                'File Error');
                            app.MessageTextArea.Value = {'Upload aborted: cannot write daily CSV.'};
                            if ~isempty(app.DataTimer) && strcmp(app.DataTimer.Running, 'off')
                                start(app.DataTimer);
                            end
                            return;
                        end
                        fprintf(fid, 'timestamp_ms,step_type\n');
                        fprintf(fid, '%s', allData);
                        fclose(fid);
                        app.MessageTextArea.Value = {
                            sprintf('Saved %d bytes to:', length(allData));
                            fullPath};
                    end
                    drawnow;
        
                    % Tell Arduino the save was successful — it will delete session files
                    pause(0.1);
                    write(app.TextChar, uint8('DONE'));
                    pause(0.3);
                    % Read [CLEARED] confirmation and lock out re-upload until next disconnect
                    try
                        clearedResp = char(read(app.TextChar));
                        if contains(clearedResp, '[CLEARED]')
                            app.DataJustCleared = true;
                        end
                    catch
                    end
                    app.MessageTextArea.Value = {
                        sprintf('Saved %d bytes to:', length(allData));
                        fullPath;
                        'Device SD card cleared.'};
                    drawnow;
                end
        
            catch ME
                app.MessageTextArea.Value = {sprintf('Upload error: %s', ME.message)};
            end
        
            % Restart battery polling
            if ~isempty(app.DataTimer) && strcmp(app.DataTimer.Running, 'off')
                start(app.DataTimer);
            end
        end

        % Button pushed function: LoadPlotButton
        function LoadPlotButtonPushed(app, event)
            [file, path] = uigetfile('*.csv', 'Select Gait Data CSV');
            if file == 0, return; end

            fullPath = fullfile(path, file);

            try
                tbl = readtable(fullPath, 'TextType', 'string');

                % Expected columns: timestamp_ms, step_type
                timestamps = tbl.timestamp_ms;
                types = tbl.step_type;

                % Convert timestamps from ms to minutes elapsed
                timeMin = (timestamps - timestamps(1)) / 60000;

                % Bin into 1-minute intervals
                binEdges = 0:1:ceil(max(timeMin));
                correctCounts = zeros(1, length(binEdges)-1);
                incorrectCounts = zeros(1, length(binEdges)-1);

                for i = 1:(length(binEdges)-1)
                    inBin = timeMin >= binEdges(i) & timeMin < binEdges(i+1);
                    correctCounts(i) = sum(inBin & types == "CORRECT");
                    incorrectCounts(i) = sum(inBin & types == "INCORRECT");
                end

                binCenters = binEdges(1:end-1) + 0.5;

                % Plot stacked bar chart
                cla(app.StepAxes);
                bar(app.StepAxes, binCenters, [correctCounts; incorrectCounts]', 'stacked');
                legend(app.StepAxes, 'Correct', 'Incorrect', 'Location', 'northwest');
                xlabel(app.StepAxes, 'Time (minutes)');
                ylabel(app.StepAxes, 'Steps');
                title(app.StepAxes, 'Step Classification Over Time');

                % Summary in tab 1 message area
                totalC = sum(correctCounts);
                totalI = sum(incorrectCounts);
                total = totalC + totalI;
                if total > 0
                    pctCorrect = round(100 * totalC / total);
                else
                    pctCorrect = 0;
                end
                app.MessageTextArea.Value = { ...
                    sprintf('Total steps: %d', total); ...
                    sprintf('Correct: %d  |  Incorrect: %d  (%d%% correct)', totalC, totalI, pctCorrect)};

            catch ME
                uialert(app.UIFigure, sprintf('Error reading CSV:\n%s', ME.message), 'Load Error');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer before anything gets destroyed
            app.IsConnected = false;
            
            if ~isempty(app.DataTimer)
                try
                    stop(app.DataTimer);
                    delete(app.DataTimer);
                catch
                end
                app.DataTimer = [];
            end
        
            % Clean up BLE
            app.TextChar = [];
            app.BLEDevice = [];
        
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Connection, Upload, & Battery';
            app.BatteryAxes = uiaxes(app.Tab1);
            title(app.BatteryAxes, 'Battery Percentage')
            xlabel(app.BatteryAxes, 'X')
            ylabel(app.BatteryAxes, 'Y')
            zlabel(app.BatteryAxes, 'Z')
            app.BatteryAxes.Visible = 'off';
            app.BluetoothConnectionPanel = uipanel(app.Tab1);
            app.BluetoothConnectionPanel.Title = 'Bluetooth Connection';
            app.ScanButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.DeviceListDropDownLabel = uilabel(app.BluetoothConnectionPanel);
            app.DeviceListDropDownLabel.Text = 'Device List';
            app.DeviceListDropDown = uidropdown(app.BluetoothConnectionPanel);
            app.DeviceListDropDown.Items = {'Scan for Devices'};
            app.DeviceListDropDown.Value = 'Scan for Devices';
            app.ConnectButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.StatusLabel = uilabel(app.BluetoothConnectionPanel);
            app.StatusLabel.Text = {'Status: '; 'Disconnected'};
            app.StatusLamp = uilamp(app.BluetoothConnectionPanel);
            app.TextfromMCULabel = uilabel(app.Tab1);
            app.TextfromMCULabel.Text = {'Status / '; 'Battery Voltage &'; 'Percentage'};
            app.MessageTextArea = uitextarea(app.Tab1);
            app.MessageTextArea.Editable = 'off';
            app.UploadDataButton = uibutton(app.Tab1, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Text = 'Upload Data';
            app.DataVisualizationTab = uitab(app.TabGroup);
            app.DataVisualizationTab.Title = 'Data Visualization';
            app.StepAxes = uiaxes(app.DataVisualizationTab);
            title(app.StepAxes, 'Step Classification Over Time')
            xlabel(app.StepAxes, 'Time (minutes)')
            ylabel(app.StepAxes, 'Steps')
            zlabel(app.StepAxes, 'Z')
            app.LoadPlotButton = uibutton(app.DataVisualizationTab, 'push');
            app.LoadPlotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPlotButtonPushed, true);
            app.LoadPlotButton.Text = 'Load .csv';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gait_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end